classdef interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        frame_windowEditField           matlab.ui.control.NumericEditField
        framewindowEditFieldLabel       matlab.ui.control.Label
        load_multi_mcd_button           matlab.ui.control.Button
        PartIyamltomcdLabel             matlab.ui.control.Label
        PartIImachinelabelLabel         matlab.ui.control.Label
        convert_yaml_to_mcdButton       matlab.ui.control.Button
        Xuan_JRLabel                    matlab.ui.control.Label
        ThanksforHengRunhuiPingQiLabel  matlab.ui.control.Label
        s2frameEditField                matlab.ui.control.NumericEditField
        frame1sEditFieldLabel           matlab.ui.control.Label
        unit2umEditField                matlab.ui.control.NumericEditField
        unitofstagepostionumLabel       matlab.ui.control.Label
        pixel2umEditField               matlab.ui.control.NumericEditField
        pixelofthevideoumLabel          matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function my_add_path(app)
            cdpath = mfilename('fullpath');
            cdpath = fileparts(cdpath);
            cd(cdpath); % enter .m filepath
            addpath(genpath(cdpath)); % add path
        end

        function assign_value(app)
            global pixel2um unit2um s2frame frame_window
            pixel2um = app.pixel2umEditField.Value;
            unit2um = app.unit2umEditField.Value;
            s2frame = app.s2frameEditField.Value;
            frame_window = app.frame_windowEditField.Value;

            global label_number_outlier label_number_beyond_edge label_number_human_flip
            label_number_outlier = 100;
            label_number_beyond_edge = 101;
            label_number_human_flip = 102;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            my_add_path(app);
            assign_value(app);            
        end

        % Value changed function: pixel2umEditField
        function pixel2umEditFieldValueChanged(app, event)
            
        end

        % Value changed function: unit2umEditField
        function unit2umEditFieldValueChanged(app, event)
         
        end

        % Value changed function: s2frameEditField
        function s2frameEditFieldValueChanged(app, event)
           
        end

        % Button pushed function: convert_yaml_to_mcdButton
        function convert_yaml_to_mcdButtonPushed(app, event)
            convert_multiple_Yaml_to_Mcd;
        end

        % Button pushed function: load_multi_mcd_button
        function load_multi_mcd_buttonPushed(app, event)
            assign_value(app);
            load_multi_mcd;
        end

        % Value changed function: frame_windowEditField
        function frame_windowEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pixelofthevideoumLabel = uilabel(app.UIFigure);
            app.pixelofthevideoumLabel.Text = '1 pixel of the video == ? um';
            app.pixel2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.pixel2umEditField.ValueChangedFcn = createCallbackFcn(app, @pixel2umEditFieldValueChanged, true);
            app.pixel2umEditField.Value = 1.6835;
            app.unitofstagepostionumLabel = uilabel(app.UIFigure);
            app.unitofstagepostionumLabel.Text = '1 unit of the stage postion == ? um';
            app.unit2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.unit2umEditField.ValueChangedFcn = createCallbackFcn(app, @unit2umEditFieldValueChanged, true);
            app.unit2umEditField.Value = 0.05;
            app.frame1sEditFieldLabel = uilabel(app.UIFigure);
            app.frame1sEditFieldLabel.Text = '? frame == 1 s';
            app.s2frameEditField = uieditfield(app.UIFigure, 'numeric');
            app.s2frameEditField.ValueChangedFcn = createCallbackFcn(app, @s2frameEditFieldValueChanged, true);
            app.s2frameEditField.Value = 66;
            app.ThanksforHengRunhuiPingQiLabel = uilabel(app.UIFigure);
            app.ThanksforHengRunhuiPingQiLabel.Text = 'Thanks for Heng, Runhui, Ping, Qi';
            app.Xuan_JRLabel = uilabel(app.UIFigure);
            app.Xuan_JRLabel.Text = 'Xuan_JR';
            app.convert_yaml_to_mcdButton = uibutton(app.UIFigure, 'push');
            app.convert_yaml_to_mcdButton.ButtonPushedFcn = createCallbackFcn(app, @convert_yaml_to_mcdButtonPushed, true);
            app.convert_yaml_to_mcdButton.Text = 'convert yaml to mcd (take about 1 minute for 10000 frames)';
            app.PartIImachinelabelLabel = uilabel(app.UIFigure);
            app.PartIImachinelabelLabel.Text = 'Part II: machine label';
            app.PartIyamltomcdLabel = uilabel(app.UIFigure);
            app.PartIyamltomcdLabel.Text = 'Part I: yaml to mcd';
            app.load_multi_mcd_button = uibutton(app.UIFigure, 'push');
            app.load_multi_mcd_button.ButtonPushedFcn = createCallbackFcn(app, @load_multi_mcd_buttonPushed, true);
            app.load_multi_mcd_button.Text = 'load multi mcd and do machine label (take about 5s for 10000 frames)';
            app.framewindowEditFieldLabel = uilabel(app.UIFigure);
            app.framewindowEditFieldLabel.Text = 'frame window';
            app.frame_windowEditField = uieditfield(app.UIFigure, 'numeric');
            app.frame_windowEditField.ValueChangedFcn = createCallbackFcn(app, @frame_windowEditFieldValueChanged, true);
            app.frame_windowEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end